classdef BlackJackGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        MusicSwitchLabel              matlab.ui.control.Label
        MusicSwitch                   matlab.ui.control.Switch
        BLACKLabel                    matlab.ui.control.Label
        JACKLabel                     matlab.ui.control.Label
        TutorialButton                matlab.ui.control.Button
        PlayNowButton                 matlab.ui.control.Button
        TutorialTab                   matlab.ui.container.Tab
        Image_4                       matlab.ui.control.Image
        TutorialLabel                 matlab.ui.control.Label
        ToplaythegamejustplayitLabel  matlab.ui.control.Label
        httpwwwhitorstandnetstrategyphpLabel  matlab.ui.control.Label
        GameTab                       matlab.ui.container.Tab
        Image_3                       matlab.ui.control.Image
        HitButton                     matlab.ui.control.Button
        StandButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Reset
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function HomeMenuSelected(app, event)
            
        end

        % Button pushed function: PlayNowButton
        function PlayNowButtonPushed(app, event)
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
        end

        % Size changed function: TutorialTab
        function TutorialButtonPushed(app, event)

        end

        % Value changed function: MusicSwitch
        function MusicSwitchValueChanged(app, event)
            value = app.MusicSwitch.Value;
            
            
            if value
                clear sound;
            end
            if value
                [y,fs]=audioread('Pacific Sun.mp3');
                x = sound(y,fs);
            
                if value 
                    clear sound;
                end
            end
            
            %{
            if value == false
            
            else
                [y,fs]=audioread('Pacific Sun.mp3')
                sound(y,fs)
                if value == false
                    clear sound
                end
        
            end
            %}
        end

        % Callback function
        function ResetMenuSelected(app, event)
           
            
        end

        % Button pushed function: TutorialButton
        function TutorialButtonPushed2(app, event)
            
            app.TabGroup = app.TutorialButton;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = '23680.jpg';
            app.MusicSwitchLabel = uilabel(app.HomeTab);
            app.MusicSwitchLabel.Text = 'Music';
            app.MusicSwitch = uiswitch(app.HomeTab, 'slider');
            app.MusicSwitch.ValueChangedFcn = createCallbackFcn(app, @MusicSwitchValueChanged, true);
            app.BLACKLabel = uilabel(app.HomeTab);
            app.BLACKLabel.Text = 'BLACK ';
            app.JACKLabel = uilabel(app.HomeTab);
            app.JACKLabel.Text = 'JACK';
            app.TutorialButton = uibutton(app.HomeTab, 'push');
            app.TutorialButton.ButtonPushedFcn = createCallbackFcn(app, @TutorialButtonPushed2, true);
            app.TutorialButton.Text = 'Tutorial';
            app.PlayNowButton = uibutton(app.HomeTab, 'push');
            app.PlayNowButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNowButtonPushed, true);
            app.PlayNowButton.Text = 'Play Now';
            app.TutorialTab = uitab(app.TabGroup);
            app.TutorialTab.SizeChangedFcn = createCallbackFcn(app, @TutorialButtonPushed, true);
            app.TutorialTab.Title = 'Tutorial';
            app.Image_4 = uiimage(app.TutorialTab);
            app.Image_4.ImageSource = 'greentable.jpg';
            app.TutorialLabel = uilabel(app.TutorialTab);
            app.TutorialLabel.Text = 'Tutorial';
            app.ToplaythegamejustplayitLabel = uilabel(app.TutorialTab);
            app.ToplaythegamejustplayitLabel.Text = {'- The goal of blackjack is to beat the dealer''s hand without going over 21.'; ''; '- Face cards are worth 10. Aces are worth 1 or 11, whichever makes a better hand.'; ''; '- Each player starts with two cards, one of the dealer''s cards is hidden until the end.'; ''; '- To ''Hit'' is to ask for another card. To ''Stand'' is to hold your total and end your turn.'; ''; '- If you go over 21 you bust, and the dealer wins regardless of the dealer''s hand.'; ''; '- If you are dealt 21 from the start (Ace & 10), you got a blackjack.'; ''; '- Blackjack usually means you win 1.5 the amount of your bet. Depends on the casino.'; ''; '- Dealer will hit until his/her cards total 17 or higher.'; ''; '- Doubling is like a hit, only the bet is doubled and you only get one more card.'; ''; '- Split can be done when you have two of the same card - the pair is split into two hands.'; ''; '- Splitting also doubles the bet, because each new hand is worth the original bet.'; ''; '- You can only double/split on the first move, or first move of a hand created by a split.'; ''; '- You cannot play on two aces after they are split.'; ''; '- You can double on a hand resulting from a split, tripling or quadrupling you bet.'};
            app.httpwwwhitorstandnetstrategyphpLabel = uilabel(app.TutorialTab);
            app.httpwwwhitorstandnetstrategyphpLabel.Text = 'http://www.hitorstand.net/strategy.php';
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.Image_3 = uiimage(app.GameTab);
            app.Image_3.ImageSource = 'greentable.jpg';
            app.HitButton = uibutton(app.GameTab, 'push');
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.GameTab, 'push');
            app.StandButton.Text = 'Stand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackJackGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end